classdef YOLO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        YOLOUIFigure              matlab.ui.Figure
        ArchitectureLabel         matlab.ui.control.Label
        Architecture              matlab.ui.control.DropDown
        FunctionLabel             matlab.ui.control.Label
        Func                      matlab.ui.control.DropDown
        TrainButton               matlab.ui.control.Button
        OCRButton                 matlab.ui.control.Button
        YearOldLegendaryOCRLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        actFunc %activation function
                %0-hardlim
                %1-linear
                %2-sigmoidal
                
        n %     %n selected architecture
                %0-amc+classifier
                %1-single classifier
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.actFunc=0;
            app.n=0;
            n=app.n;
            save 'n.mat' n;
            actFunc=app.actFunc;
            save 'actfunc.mat' actFunc;
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            training(app.actFunc,app.n);
        end

        % Button pushed function: OCRButton
        function OCRButtonPushed(app, event)
            mpaper();
        end

        % Value changed function: Architecture
        function ArchitectureValueChanged(app, event)
            value = app.Architecture.Value;
            switch value
                case 'AM+Classifier'
                    app.n=0;
                otherwise
                    app.n=1;
            end
            n=app.n;
            save 'n.mat' n;
        end

        % Value changed function: Func
        function FuncValueChanged(app, event)
            value = app.Func.Value;
            switch value
                case 'hardlim'
                    app.actFunc=0;
                case 'linear'
                    app.actFunc=1;
                otherwise
                    app.actFunc=2;
            end
            actFunc=app.actFunc;
            save 'actfunc.mat' actFunc;
        end

        % Close request function: YOLOUIFigure
        function YOLOUIFigureCloseRequest(app, event)
            delete(app)
            clear;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.YOLOUIFigure = uifigure;
            app.YOLOUIFigure.Name = 'Y.O.L.O.';
            app.YOLOUIFigure.CloseRequestFcn = createCallbackFcn(app, @YOLOUIFigureCloseRequest, true);
            app.ArchitectureLabel = uilabel(app.YOLOUIFigure);
            app.ArchitectureLabel.Text = 'Architecture';
            app.Architecture = uidropdown(app.YOLOUIFigure);
            app.Architecture.Items = {'AM+Classifier', 'Clasifier'};
            app.Architecture.ValueChangedFcn = createCallbackFcn(app, @ArchitectureValueChanged, true);
            app.Architecture.Value = 'AM+Classifier';
            app.FunctionLabel = uilabel(app.YOLOUIFigure);
            app.FunctionLabel.Text = 'Function';
            app.Func = uidropdown(app.YOLOUIFigure);
            app.Func.Items = {'hardlim', 'linear', 'sigmoidal'};
            app.Func.ValueChangedFcn = createCallbackFcn(app, @FuncValueChanged, true);
            app.Func.Value = 'hardlim';
            app.TrainButton = uibutton(app.YOLOUIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.OCRButton = uibutton(app.YOLOUIFigure, 'push');
            app.OCRButton.ButtonPushedFcn = createCallbackFcn(app, @OCRButtonPushed, true);
            app.OCRButton.Text = 'OCR';
            app.YearOldLegendaryOCRLabel = uilabel(app.YOLOUIFigure);
            app.YearOldLegendaryOCRLabel.Text = 'Year Old Legendary OCR';
        end
    end

    methods (Access = public)

        % Construct app
        function app = YOLO

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.YOLOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.YOLOUIFigure)
        end
    end
end